classdef GemstoneClassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PredictionsButton  matlab.ui.control.Button
        GradCAMButton      matlab.ui.control.Button
        SimilarButton      matlab.ui.control.Button
        ConfidenceLabel    matlab.ui.control.Label
        PredictionLabel    matlab.ui.control.Label
        ShowMetricsButton  matlab.ui.control.Button
        SelectImageButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end


properties (Access = private)
        netTrained               
        LastScores               
        LastClasses             
        LastPredictedClass       
        OriginalImage           
        StoneArea               
        BBox                     
        Diameter              
        AspectRatio              
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
  
    data = load('gemstoneModel.mat'); 
    app.netTrained = data.netTrained;

                
            app.LastScores         = [];
            app.LastClasses        = [];
            app.LastPredictedClass = "";
            app.OriginalImage      = [];
            app.StoneArea          = NaN;
            app.BBox               = [];
            app.Diameter           = NaN;
            app.AspectRatio        = NaN;
        

        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)

            
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png','Image Files'}, ...
                                     'Select an image');
            if isequal(file,0)
                return;
            end

            img = imread(fullfile(path, file));
            app.OriginalImage = img;           

            
            imshow(img, "Parent", app.UIAxes);
            title(app.UIAxes, "Selected Image");

            
            imgResized = imresize(img, [224 224]);
            scores = predict(app.netTrained, imgResized);

           
            [~, idx] = max(scores);
            classes = app.netTrained.Layers(end).Classes;
            predictedClass = string(classes(idx));

            
            app.PredictionLabel.Text = sprintf("Prediction: %s", predictedClass);
            confidence = max(scores) * 100;
            app.ConfidenceLabel.Text = sprintf("Confidence: %.2f%%", confidence);

            
            app.LastScores         = scores;
            app.LastClasses        = string(classes);
            app.LastPredictedClass = predictedClass;

            
            gray = rgb2gray(imgResized);

            bw = imbinarize(gray);
            bw = imopen(bw, strel("disk", 3));
            bw = imfill(bw, "holes");

            labeled = bwlabel(bw);
            stats = regionprops(labeled, "Area", "BoundingBox", "EquivDiameter");

            if isempty(stats)
                
                app.StoneArea   = NaN;
                app.BBox        = [];
                app.Diameter    = NaN;
                app.AspectRatio = NaN;
                return;
            end

           
            [~, maxIdx] = max([stats.Area]);
            stone = stats(maxIdx);

            stoneArea  = stone.Area;
            totalArea  = numel(gray);
            areaPercent = (stoneArea / totalArea) * 100;

            bbox      = stone.BoundingBox;   
            widthPx   = bbox(3);
            heightPx  = bbox(4);
            diameter  = stone.EquivDiameter;
            aspect    = widthPx / heightPx;

            
            app.StoneArea   = areaPercent;
            app.BBox        = bbox;
            app.Diameter    = diameter;
            app.AspectRatio = aspect;
        
        end

        % Button pushed function: ShowMetricsButton
        function ShowMetricsButtonPushed(app, event)


            img = getimage(app.UIAxes);
            if isempty(img)
                uialert(app.UIFigure, "Please load an image first!", "No Image");
                return;
            end

            
            if isnan(app.StoneArea) || isempty(app.BBox)
                imgResized = imresize(img, [224 224]);
                gray = rgb2gray(imgResized);

                bw = imbinarize(gray);
                bw = imopen(bw, strel("disk", 3));
                bw = imfill(bw, "holes");

                labeled = bwlabel(bw);
                stats = regionprops(labeled, "Area", "BoundingBox", "EquivDiameter");

                if isempty(stats)
                    uialert(app.UIFigure, "Stone could not be detected!", "Error");
                    return;
                end

                [~, maxIdx] = max([stats.Area]);
                stone = stats(maxIdx);

                stoneArea  = stone.Area;
                totalArea  = numel(gray);
                areaPercent = (stoneArea / totalArea) * 100;

                bbox      = stone.BoundingBox;
                widthPx   = bbox(3);
                heightPx  = bbox(4);
                diameter  = stone.EquivDiameter;
                aspect    = widthPx / heightPx;

                app.StoneArea   = areaPercent;
                app.BBox        = bbox;
                app.Diameter    = diameter;
                app.AspectRatio = aspect;
            end

            
            areaPercent = app.StoneArea;
            bbox        = app.BBox;
            diameter    = app.Diameter;
            aspect      = app.AspectRatio;

            
            fig = figure("Name", "Stone Metrics", ...
                         "Position", [200 200 400 250]);
uit = uitable(fig);

uit.Data = {
    'Stone Area (%)',        sprintf('%.2f %%', areaPercent);
    'Bounding Box',          sprintf('%.0f x %.0f px', bbox(3), bbox(4));
    'Estimated Diameter',    sprintf('%.1f px', diameter);
    'Aspect Ratio (W/H)',    sprintf('%.2f', aspect)
};

uit.ColumnName = {'Metric', 'Value'};
uit.RowName = {};
uit.Position = [20 20 360 300];


        end

        % Button pushed function: SimilarButton
        function SimilarButtonPushed(app, event)
          if isempty(app.LastScores)
                uialert(app.UIFigure, 'Please classify an image first!', 'No Data');
                return;
            end

            
            data = load("gemstoneFeatures.mat");
            trainFeatures = data.trainFeatures;
            trainFiles    = data.trainFiles;

            featureLayer = "pool5";

            
            img = getimage(app.UIAxes);
            imgResized = imresize(img, [224 224]);
            queryFeat = activations(app.netTrained, imgResized, ...
                                    featureLayer, "OutputAs", "rows");

            simScores = 1 - pdist2(queryFeat, trainFeatures, "cosine");

            
            trueClasses = strings(size(trainFiles));
            for i = 1:numel(trainFiles)
                parts = split(trainFiles{i}, filesep);
                trueClasses(i) = string(parts(end-1));
            end

            
            predictedClass = string( ...
                strtrim(erase(app.PredictionLabel.Text, "Prediction:")) );

            
            keepIdx = trueClasses ~= predictedClass;

            filteredFeatures = trainFeatures(keepIdx, :); 
            filteredFiles    = trainFiles(keepIdx);
            filteredClasses  = trueClasses(keepIdx);
            filteredScores   = simScores(keepIdx);

            
            [~, sortedIdx] = sort(filteredScores, "descend");

            uniqueClassList = strings(0);
            finalIdx = [];

            for k = 1:numel(sortedIdx)
                cls = filteredClasses(sortedIdx(k));
                if ~ismember(cls, uniqueClassList)
                    uniqueClassList(end+1) = cls; 
                    finalIdx(end+1) = sortedIdx(k); 
                end
                if numel(finalIdx) == 5
                    break;
                end
            end

            nearestFiles   = filteredFiles(finalIdx);
            nearestClasses = filteredClasses(finalIdx);

           
            figure("Name", "Top-5 Similar Stones (Unique Classes)");
            tiledlayout(1,5);
            for i = 1:5
                nexttile;
                imshow(nearestFiles{i});
                title(nearestClasses(i), "FontSize", 12, "FontWeight", "bold");
            end
        end

        % Button pushed function: PredictionsButton
        function PredictionsButtonPushed(app, event)

    img = getimage(app.UIAxes);
    if isempty(img)
        uialert(app.UIFigure, "Please load an image first!", "No Image");
        return;
    end

    
    imgResized = imresize(img, [224 224]);

    
    scores = predict(app.netTrained, imgResized);
    scores = squeeze(scores);   

   
    classNames = string(app.netTrained.Layers(end).Classes);

    
    [sortedScores, idx] = maxk(scores, 5);
    topClasses = classNames(idx);

    
    fig = figure("Name", "Top-5 Predictions", ...
                 "Position", [350 350 400 250]);

    uit = uitable(fig);

    
    data = cell(5,2);
    for i = 1:5
        data{i,1} = char(topClasses(i));                 
        data{i,2} = sprintf('%.2f %%', sortedScores(i)*100); 
    end

    uit.Data = data;
    uit.ColumnName = {'Class', 'Probability'};
    uit.Position = [20 20 360 200];
        end

        % Button pushed function: GradCAMButton
        function GradCAMButtonPushed(app, event)


    img = getimage(app.UIAxes);
    if isempty(img)
        uialert(app.UIFigure, "Please load an image first!", "No Image");
        return;
    end

    
    imgResized = imresize(img, [224 224]);

    
    scores = predict(app.netTrained, imgResized);
    [~, idx] = max(scores);

    classNames = app.netTrained.Layers(end).Classes;
    targetClass = classNames(idx);

  
    featureLayer = "res5b_relu";   

    
    camMap = gradCAM(app.netTrained, imgResized, targetClass, ...
                     "FeatureLayer", featureLayer);

    camMap = rescale(camMap);

 
    fig = figure("Name", "Grad-CAM Visualization", ...
                 "Position", [300 200 900 300]);

    tiledlayout(1,3,"Padding","compact","TileSpacing","compact");

   
    nexttile;
    imshow(imgResized);
    title("Original Image");

    
    nexttile;
    imagesc(camMap);
    axis image off;
    colormap jet;
    title("Grad-CAM Heatmap");

    
    nexttile;
    imshow(imgResized);
    hold on;
    imagesc(camMap,'AlphaData',0.45);
    colormap jet;
    axis off;
    title("Overlay");


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ImageAxes')
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Tag = 'SelectImageButton';
            app.SelectImageButton.Text = 'SelectImageButton';
            app.ShowMetricsButton = uibutton(app.UIFigure, 'push');
            app.ShowMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMetricsButtonPushed, true);
            app.ShowMetricsButton.Tag = 'ShowMetricsButton';
            app.ShowMetricsButton.Text = 'ShowMetricsButton';
            app.PredictionLabel = uilabel(app.UIFigure);
            app.PredictionLabel.Tag = 'PredictionLabel';
            app.PredictionLabel.Text = 'Prediction:';
            app.ConfidenceLabel = uilabel(app.UIFigure);
            app.ConfidenceLabel.Tag = 'ConfidenceLabel';
            app.ConfidenceLabel.Text = 'Confidence:';
            app.SimilarButton = uibutton(app.UIFigure, 'push');
            app.SimilarButton.ButtonPushedFcn = createCallbackFcn(app, @SimilarButtonPushed, true);
            app.SimilarButton.Tag = 'SimilarButton';
            app.SimilarButton.Text = 'SimilarButton';
            app.GradCAMButton = uibutton(app.UIFigure, 'push');
            app.GradCAMButton.ButtonPushedFcn = createCallbackFcn(app, @GradCAMButtonPushed, true);
            app.GradCAMButton.Text = 'GradCAMButton';
            app.PredictionsButton = uibutton(app.UIFigure, 'push');
            app.PredictionsButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionsButtonPushed, true);
            app.PredictionsButton.Tag = 'PredictionsButton';
            app.PredictionsButton.Text = 'Predictions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GemstoneClassifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end